classdef onlyLive < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        LiveRecognitionButton  matlab.ui.control.Button
        ClassificationOutput   matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        live = false
    end
    
    methods (Access = public)
        
        function results = func(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load googlenett;
        end

        % Button pushed function: LiveRecognitionButton
        function LiveRecognitionButtonPushed(app, event)
            x = 0;
            y = 0;
            h = 224;
            w = 224;
            box = [x y h w];
            cam = webcam(1);
            app.live = true;
            while (app.live)
                snap = snapshot(cam);
                PA = insertObjectAnnotation(snap, 'rectangle',box, 'Place hand here');
                hand = imcrop(snap, box);
                hand = imresize(hand, [224 224]);
                imshow(PA, 'parent', app.UIAxes);
                output = classify(trained_Network1, hand);
                app.ClassificationOutput.Text = char(output);
                drawnow;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LiveRecognitionButton = uibutton(app.UIFigure, 'push');
            app.LiveRecognitionButton.ButtonPushedFcn = createCallbackFcn(app, @LiveRecognitionButtonPushed, true);
            app.LiveRecognitionButton.Text = 'Live Recognition';
            app.ClassificationOutput = uilabel(app.UIFigure);
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = onlyLive

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end